classdef musicPlayer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Menu            matlab.ui.container.Menu
        add_file        matlab.ui.container.Menu
        add_folder      matlab.ui.container.Menu
        play_and_pause  matlab.ui.control.Button
        process         matlab.ui.control.Slider
        UIAxes          matlab.ui.control.UIAxes
        Tree            matlab.ui.container.Tree
        Label_4         matlab.ui.control.Label
        PLAYSPEED       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        % 查看audioread文档找到支持哪一些文件
        extendsions = {'.ogg';'.mp3';'.wav';'.flac';'.au';'.aif';'.aiff';'.mp4';'.m4a'}
        total_frame % 音频长度，用于进度条的更新
        fs % 音频采样率
        player % 音频播放器
        play_speed = 1 % 播放速度
        audio_data % 音频数据
        my_timer % 定时器
        fragment_size = 0.1 % 用于求条形图的音频片段大小，单位为秒
        bar_handler % 条形图句柄
    end
    
    methods (Access = private)
        
        function load_node(app, node)
            % 提取节点的名字以及路径
            file = node.Text;
            path = node.NodeData;
            full_path = fullfile(path, file);
            % 判断文件是否存在
            if ~exist(full_path, 'file')
                errordlg("文件不存在");
                return;
            end
            % 读取音频
            [app.audio_data, app.fs] = audioread(fullfile(path, file));
            % 即使app.player为空，使用pause也不会出错的。
            pause(app.player);
            % 更新音频长度
            app.total_frame = size(app.audio_data,1);
            % 更新音乐播放器
            app.player = audioplayer(app.audio_data, app.play_speed*app.fs);
            % 进行播放，这样子不用我们自己去点击按钮就直接播放。
            play(app.player);
        end
   
        function node = addNode(app, file, path)
            % 用于新建节点
            node = uitreenode(app.Tree);
            node.Text = file;
            node.NodeData = path;
        end
        
        function draw_bar(app)
            % 计算片段左右侧索引
            half_space = floor(app.fs*app.play_speed*app.fragment_size/2);
            left = app.player.CurrentSample - half_space;
            if left<1
                left = 1;
            end
            right = app.player.CurrentSample + half_space;
            if right>app.total_frame
                right = app.total_frame;
            end
            % 取出片段，用于计算波形图
            X = app.audio_data(left:right,:);
            % 将音频左右声道合并
            X = sum(X, 2);
            L = length(X);
            Y = fft(X);
            tmp = abs(Y/L);
            P1 = 2*tmp(1:floor(L/2)+1);
            freq = app.fs*app.play_speed*(0:(L/2))/L;
            P2 = P1(freq<2500&freq>20);
            freq2 = freq(freq<2500&freq>20);
            window = floor(length(freq2)/32);
            XData = smooth(freq2, window);
            YData = smooth(P2, window);
            XData = XData(1:window:end);
            YData = YData(1:window:end);
            
            set(app.bar_handler, 'XData', XData, 'YData', YData);
        end
        
        function update_process(app, hObject, eventdata)
            % 判断音乐播放器是否不为空以及是否正在播放音乐，如果是才执行下面的更新逻辑。
            if ~isempty(app.player)&&strcmpi(get(app.player,'running'),'on')
                % 更新进度条位置
                app.process.Value = app.player.CurrentSample/app.total_frame;
                % 更新波形图
                app.draw_bar();
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 初始化波形图句柄
            app.bar_handler = bar(app.UIAxes, 0, 0);
            % 限制Y轴显示范围
            ylim(app.UIAxes, [0, 0.2]);
            % 创建定时器   
            app.my_timer = timer("ExecutionMode", "fixedSpacing", 'Period', 0.1, "TimerFcn", @app.update_process);
            % 启动定时器
            start(app.my_timer);
        end

        % Button pushed function: play_and_pause
        function play_and_pauseButtonPushed(app, event)
            % 判断播放器是否为空，如果为空则不往下执行
            if isempty(app.player)
                return
            end
            % 如果播放器处于暂停状态那么就继续播放，如果为播放状态那么就暂停播放
            if strcmpi(get(app.player,'running'),'on')
                % 暂停播放
                pause(app.player);
            else
                %继续播放
                resume(app.player);
            end
        end

        % Value changed function: process
        function processValueChanged(app, event)
            value = app.process.Value;
            % 判断播放器是否为空，如果为空则不往下执行
            if isempty(app.player)
                return
            end
            % 如果播放器是否处于播放状态，如果是则先暂停
            status = strcmpi(get(app.player,'running'),'on');
            if status
                pause(app.player);
            end
            % 从指定帧开始继续播放
            play(app.player, round(value*app.total_frame));
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            % 加载音频
            app.load_node(selectedNodes);
        end

        % Menu selected function: add_file
        function add_fileMenuSelected(app, event)
            s = '';
            for index = 1:length(app.extendsions)
                s = strcat(s, '*', app.extendsions{index}, ';');
            end
            [files,path] = uigetfile({s, "音频文件"}, 'MultiSelect', 'on');
            % 如果没有选择文件，则返回
            if ~ischar(path)
                return
            end
            % 如果是一个文件
            if ~iscell(files)
                app.addNode(files, path);
                return
            end
            % 如果多个文件
            for i = 1:length(files)
                app.addNode(files{i}, path);
            end
        end

        % Menu selected function: add_folder
        function add_folderMenuSelected(app, event)
            path = uigetdir;
            % 如果没有选择文件，则返回
            if ~ischar(path)
                return
            end
            files = batch_load(path, app.extendsions{:});
            f = waitbar(0,'正在添加文件...', 'WindowStyle', 'modal');
            % 用于取消加载
            try
                for i = 1:length(files)
                    [filepath, name, ext] = fileparts(files{i});
                    app.addNode([name, ext], filepath);
                    % 每增加50个刷新一次，太频繁可能会卡顿
                    if mod(i, 50) == 0
                        waitbar(i/length(files),f);
                    end
                end
                waitbar(1,f);
                close(f);
            catch
            end
        end

        % Value changed function: PLAYSPEED
        function PLAYSPEEDValueChanged(app, event)
            if isempty(app.player)
                return
            end
            status = strcmpi(get(app.player,'running'),'on');
            if status
                % 暂停播放器
                pause(app.player);
            end
            % 记录当前帧
            current_frame = app.player.CurrentSample;
            % 改变播放速度
            app.play_speed = str2double(app.PLAYSPEED.Value);
            app.player.SampleRate = app.play_speed*app.fs;
            if status
                % 重新从当前帧播放
                play(app.player, current_frame);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % 暂停并删除音乐句柄
            pause(app.player);
            delete(app.player);
            % 暂停并删除定时器
            stop(app.my_timer);
            delete(app.my_timer);
            % 删出当前应用
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '添加';
            app.add_file = uimenu(app.Menu);
            app.add_file.MenuSelectedFcn = createCallbackFcn(app, @add_fileMenuSelected, true);
            app.add_file.Text = '文件';
            app.add_folder = uimenu(app.Menu);
            app.add_folder.MenuSelectedFcn = createCallbackFcn(app, @add_folderMenuSelected, true);
            app.add_folder.Text = '文件夹';
            app.play_and_pause = uibutton(app.UIFigure, 'push');
            app.play_and_pause.ButtonPushedFcn = createCallbackFcn(app, @play_and_pauseButtonPushed, true);
            app.play_and_pause.Text = '播放/暂停';
            app.process = uislider(app.UIFigure);
            app.process.Limits = [0 1];
            app.process.MajorTicks = [];
            app.process.MajorTickLabels = {''};
            app.process.ValueChangedFcn = createCallbackFcn(app, @processValueChanged, true);
            app.process.MinorTicks = [];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {};
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '播放速度';
            app.PLAYSPEED = uidropdown(app.UIFigure);
            app.PLAYSPEED.Items = {'0.5', '0.75', '1', '1.25', '1.5', '2'};
            app.PLAYSPEED.ValueChangedFcn = createCallbackFcn(app, @PLAYSPEEDValueChanged, true);
            app.PLAYSPEED.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = musicPlayer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end